classdef iceControlPanel < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      Figure                   matlab.ui.Figure
      CurveAxes                matlab.ui.control.UIAxes
      ComponentLabel           matlab.ui.control.Label
      InputFieldLabel          matlab.ui.control.Label
      OutputFieldLabel         matlab.ui.control.Label
      InputTextField           matlab.ui.control.Label
      OutputTextField          matlab.ui.control.Label
      ResetAllButton           matlab.ui.control.Button
      BlueValueDisplay         matlab.ui.control.Label
      GreenValueDisplay        matlab.ui.control.Label
      RedValueDisplay          matlab.ui.control.Label
      PseudoColorBarLabel      matlab.ui.control.Label
      FullColorBarLabel        matlab.ui.control.Label
      MapBarsCheckbox          matlab.ui.control.CheckBox
      MapImageCheckbox         matlab.ui.control.CheckBox
      CurveOptionsPanel        matlab.ui.container.Panel
      SmoothCheckbox           matlab.ui.control.CheckBox
      ResetCurveOptionsButton  matlab.ui.control.Button
      SlopeCheckbox            matlab.ui.control.CheckBox
      ShowPDFCheckbox          matlab.ui.control.CheckBox
      ShowCDFCheckbox          matlab.ui.control.CheckBox
      ColorComponentsSelector  matlab.ui.control.DropDown
   end

%   Written by Steve Eddins to accompany Digital Image Processing Using
%   MATLAB, 3rd edition, Gatesmark Press, 2020,
%   http://imageprocessingplace.com.
%
%   Copyright 2019-2020 The MathWorks, Inc.
%   License: https://github.com/mathworks/matlab-color-tools/blob/master/license.txt

    properties (Access = private)
        MouseUpDownState  = 'none'
        PlotAreaParameters = [0 0 1 1]
        Sets = {[0 0; 1 1], [0 0; 1 1], [0 0; 1 1], [0 0; 1 1]}
        CurveIndex   = 1
        SelectedControlPoint = 0
        NodeBelowControlPoint = 1
        NodeAboveControlPoint = 2
        CurveSmoothingStates = [0; 0; 0; 0]
        CurveEndSlopeControlStates  = [0; 0; 0; 0]
        CurveCDFStates = [0; 0; 0; 0]
        CurvePDFStates = [0; 0; 0; 0]
        InputPDFCDF = []   % Input PDFs and CDFs
        ImageMapEnabled = 1
        BarMapEnabled = 1
        GrayBar
        ColorBar
        GrayBarImage
        ColorBarImage
    end
   
   properties (Access = public)
      InputImage
      InputImageColorSpace
      OutputImageFigure
   end
    
    methods (Access = private)
        function g = cmy2rgb(~, f)
            %   Convert CMY to RGB using imcomplement.
            g = imcomplement(f);
        end
        
        function [inplot, x, y] = cursor(app)
            %  Translate the mouse position to a coordinate with respect to
            %  the current plot area. Check for the mouse in the area; if
            %  so, save the location and write the coordinates below the plot.
            
            p = app.Figure.CurrentPoint;
            x = (p(1, 1) - app.PlotAreaParameters(1)) / app.PlotAreaParameters(3);
            y = (p(1, 2) - app.PlotAreaParameters(2)) / app.PlotAreaParameters(4);
            if x > 1.05 || x < -0.05 || y > 1.05 || y < -0.05
               inplot = 0;
            else
               x = min(x, 1);      
               x = max(x, 0);
               y = min(y, 1);      
               y = max(y, 0);
               x = round(256 * x) / 256;
               inplot = 1;
               app.InputTextField.Text = num2str(x, 3);
               app.OutputTextField.Text = num2str(y, 3);
            end
        end
        
        function graph(app)
           %  Interpolate and plot mapping functions and optional reference
           %  PDF(s) or CDF(s).
           
           c = app.CurveIndex;
           nodes = app.Sets{c};
           dfx = linspace(0,1,256);
           colors = ['k' 'r' 'g' 'b'];
           
           % For piecewise linear interpolation, plot a map, map + PDF/CDF, or
           % map + 3 PDFs/CDFs.
           if ~app.CurveSmoothingStates(app.CurveIndex)
              if (~app.CurvePDFStates(c) && ~app.CurveCDFStates(c)) || ...
                    (size(app.InputPDFCDF, 2) == 0)
                 plot(nodes(:, 1), nodes(:, 2), 'b-', ...
                    nodes(:, 1),  nodes(:, 2), 'ko', ...
                    'LineWidth', 1.5, ...
                    'Parent', app.CurveAxes);
              elseif c > 1
                 i = 2 * c - 2 - app.CurvePDFStates(c);
                 plot(dfx, app.InputPDFCDF(i, :), [colors(c) '-'], ...
                    nodes(:, 1), nodes(:, 2), 'k-', ...
                    nodes(:, 1), nodes(:, 2), 'ko', ...
                    'LineWidth', 1.5, ...
                    'Parent', app.CurveAxes);
              elseif c == 1
                 i = app.CurveCDFStates(c);
                 plot(dfx, app.InputPDFCDF(i + 1, :), 'r-', ...
                    dfx, app.InputPDFCDF(i + 3, :), 'g-',  ...
                    dfx, app.InputPDFCDF(i + 5, :), 'b-', ...
                    nodes(:, 1), nodes(:, 2), 'k-',  ...
                    nodes(:, 1), nodes(:, 2), 'ko', ...
                    'LineWidth', 1.5, ...
                    'Parent', app.CurveAxes);
              end
              
           else
              % Do the same for smooth (cubic spline) interpolations.
               
              x = 0:0.01:1;
              if ~app.CurveEndSlopeControlStates(app.CurveIndex)
                 y = spline(nodes(:, 1), nodes(:, 2), x);
              else
                 y = spline(nodes(:, 1), [0; nodes(:, 2); 0], x);
              end
              y = min(y,1);
              y = max(y,0);
              
              if (~app.CurvePDFStates(c) && ~app.CurveCDFStates(c)) || ...
                    (size(app.InputPDFCDF, 2) == 0)
                 plot(nodes(:, 1), nodes(:, 2), 'ko',  x, y, 'b-', ...
                     'LineWidth', 1.5, ...
                    'Parent', app.CurveAxes);
              elseif c > 1
                 i = 2 * c - 2 - app.CurvePDFStates(c);
                 plot(dfx, app.InputPDFCDF(i, :), [colors(c) '-'], ...
                    nodes(:, 1), nodes(:, 2), 'ko', x, y, 'k-', ...
                    'LineWidth', 1.5, ...
                    'Parent', app.CurveAxes);
              elseif c == 1
                 i = app.CurveCDFStates(c);
                 plot(dfx, app.InputPDFCDF(i + 1, :), 'r-', ...
                    dfx, app.InputPDFCDF(i + 3, :), 'g-', ...
                    dfx, app.InputPDFCDF(i + 5, :), 'b-', ...
                    nodes(:, 1), nodes(:, 2), 'ko', x, y, 'k-', ...
                    'LineWidth', 1.5, ...
                    'Parent', app.CurveAxes);
              end
           end
           
           % Put legend if more than two curves are shown.
           s = app.InputImageColorSpace;
           if strcmp(s, 'ntsc')
              s = 'yiq';
           end
           if (c == 1) && (app.CurvePDFStates(c) || app.CurveCDFStates(c))
              s1 = ['-- ' upper(s(1))];
              if length(s) == 3
                 s2 = ['-- ' upper(s(2))];
                 s3 = ['-- ' upper(s(3))];
              else
                 s2 = ['-- ' upper(s(2)) s(3)];
                 s3 = ['-- ' upper(s(4)) s(5)];
              end
           else
              s1 = '';
              s2 = '';
              s3 = '';
           end
           app.RedValueDisplay.Text = s1;
           app.GreenValueDisplay.Text = s2;
           app.BlueValueDisplay.Text = s3;
        end
        
        function t = lut(~, nodes, smooth, slope)
           %  Create a 256 element mapping function from a set of control
           %  points. The output values are integers in the interval [0, 255].
           %  Use piecewise linear or cubic spline with or without zero end
           %  slope interpolation.
           
           t = 255 * nodes;
           i = 0:255;
           if ~smooth
              t = [t; 256 256];
              t = interp1(t(:, 1), t(:, 2), i');
           else
              if ~slope
                 t = spline(t(:, 1), t(:, 2), i);
              else
                 t = spline(t(:, 1), [0; t(:, 2); 0], i);
              end
           end
           t = round(t);
           t = max(0, t);
           t = min(255, t);
        end
        
        function render(app)
           %  Map the input image and bar components and convert them to RGB
           %  (if needed) and display.
           
           app.Figure.Interruptible = 'off';
           ygb = app.GrayBar;
           ycb = app.ColorBar;
           yi = app.InputImage;
           mapon = app.BarMapEnabled;
           imageon = app.ImageMapEnabled & size(app.InputImage, 1);
           
           for i = 2:4
              nodes = app.Sets{i};
              t = lut(app,nodes, app.CurveSmoothingStates(i), app.CurveEndSlopeControlStates(i));
              if imageon
                 yi(:, :, i - 1) = t(yi(:, :, i - 1) + 1);
              end
              if mapon
                 ygb(:, :, i - 1) = t(ygb(:, :, i - 1) + 1);
                 ycb(:, :, i - 1) = t(ycb(:, :, i - 1) + 1);
              end
           end
           % t = lut(app, app.set1, app.CurveSmoothingStates(1), app.CurveEndSlopeControlStates(1));
           t = lut(app, app.Sets{1}, app.CurveSmoothingStates(1), app.CurveEndSlopeControlStates(1));
           if imageon
              yi = t(yi + 1);
           end
           if mapon
              ygb = t(ygb + 1);
              ycb = t(ycb + 1);
           end
           
           if strcmp(app.InputImageColorSpace, 'cmy')
              if size(app.InputImage, 1)
                 yi = yi / 255;
                 yi = cmy2rgb(app,yi);
                 yi = uint8(255 * yi);
              end
              ygb = ygb / 255;
              ycb = ycb / 255;
              ygb = cmy2rgb(app,ygb);
              ycb = cmy2rgb(app,ycb);
              ygb = uint8(255 * ygb);
              ycb = uint8(255 * ycb);
           elseif strcmp(app.InputImageColorSpace,'hsi')
              if size(app.InputImage, 1)
                 yi = yi / 255;
                 yi = hsi2rgb(yi);
                 yi = uint8(255 * yi);
              end
              ygb = ygb / 255;
              ycb = ycb / 255;
              ygb = hsi2rgb(ygb);
              ycb = hsi2rgb(ycb);
              ygb = uint8(255 * ygb);
              ycb = uint8(255 * ycb);
           else
              yi = uint8(yi);
              ygb = uint8(ygb);
              ycb = uint8(ycb);
           end
                      
           if size(app.InputImage, 1)
              if isempty(app.OutputImageFigure.CurrentAxes)
                 app.OutputImageFigure.CurrentAxes = axes;
              end
              imshow(yi,'Parent',app.OutputImageFigure.CurrentAxes);
           end
           ygb = repmat(ygb, [32 1 1]);
           ycb = repmat(ycb, [32 1 1]);
           if ~verLessThan('matlab','9.6')
              app.GrayBarImage.ImageSource = ygb;
              app.ColorBarImage.ImageSource = ycb;
           end
           
           figure(app.Figure);
           app.Figure.Interruptible = 'on';
        end
        
        function g = rgb2cmy(~, f)
           %   Convert RGB to CMY using IPT's imcomplement.
           
           g = imcomplement(f);
        end
        
        function out = spreadout(~, in)
           %  Make all x values unique.
           
           % Scan forward for non-unique x's and bump the higher indexed x--
           % but don't exceed 1. Scan the entire range.
           nudge = 1 / 256;
           for i = 2:size(in, 1) - 1
              if in(i, 1) <= in(i - 1, 1)
                 in(i, 1) = min(in(i - 1, 1) + nudge, 1);
              end
           end
           
           % Scan in reverse for non-unique x's and decrease the lower indexed
           % x -- but don't go below 0. Stop on the first non-unique pair.
           if in(end, 1) == in(end - 1, 1)
              for i = size(in, 1):-1:2
                 if in(i, 1) <= in(i - 1, 1)
                    in(i - 1, 1) = max(in(i, 1) - nudge, 0);
                 else
                    break;
                 end
              end
           end
           
           % If the first two x's are now the same, init the curve.
           if in(1, 1) == in(2, 1)
              in = [0 0; 1 1];
           end
           out = in;
        end
        
    end
    

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function ice_OpeningFcn(app, f, colorspace)
         if nargin < 2
             f = imread('peppers.png');
         end
         if nargin < 3
             colorspace = 'rgb';
         end
         
         app.InputImage = im2double(f);
         app.InputImageColorSpace = colorspace;
                  
         if strcmp(app.InputImageColorSpace,'cmy')
            app.ColorComponentsSelector.Items = {'CMY','Cyan','Magenta','Yellow'};
            app.ColorComponentsSelector.Value = 'CMY';
         elseif strcmp(app.InputImageColorSpace,'hsi')
            app.ColorComponentsSelector.Items = {'HSI','Hue','Saturation','Intensity'};
            app.ColorComponentsSelector.Value = 'HSI';
         end
         
         % Create pseudo- and full-color mapping bars (grays and hues). Store
         % a color space converted 1x128x3 line of each bar for mapping.
         xi = linspace(0,1,128);
         x = linspace(0,1,7);
         x = x';
         y = [1 1 0 0 0 1 1; 0 1 1 1 0 0 0; 0 0 0 1 1 1 0]';
         gb = repmat(xi, [1 1 3]);
         cb = interp1(x, y, xi');
         cb = reshape(cb, [1 128 3]);
         
         if strcmp(app.InputImageColorSpace,'cmy')
            gb = rgb2cmy(app,gb);
            cb = rgb2cmy(app,cb);
         elseif strcmp(app.InputImageColorSpace,'hsi')
            gb = rgb2hsi(gb);
            cb = rgb2hsi(cb);
         end
         
         gb = round(255 * gb);
         gb = max(0, gb);
         gb = min(255, gb);
         cb = round(255 * cb);
         cb = max(0, cb);
         cb = min(255, cb);
         app.GrayBar = gb;
         app.ColorBar = cb;
         
         % Do color space transforms, clamp to [0, 255], compute histograms
         % and cumulative distribution functions, and create output figure.
         if size(app.InputImage, 1)
            if strcmp(app.InputImageColorSpace,'cmy')
               app.InputImage = rgb2cmy(app,app.InputImage);
            elseif strcmp(app.InputImageColorSpace,'hsi')
               app.InputImage = rgb2hsi(app.InputImage);
            end
            app.InputImage = round(255 * app.InputImage);
            app.InputImage = max(0, app.InputImage);
            app.InputImage = min(255, app.InputImage);
            for i = 1:3
               color = app.InputImage(:, :, i);
               df = histcounts(color(:),'BinMethod','integers','BinLimits',[0 255]);
               app.InputPDFCDF = [app.InputPDFCDF; df / max(df(:))];
               df = df / sum(df(:));
               df = cumsum(df);
               app.InputPDFCDF = [app.InputPDFCDF; df];
            end
            app.OutputImageFigure = figure;
         end
         
         % Compute ICE's screen position and display image/graph.
         graphics_root = groot;
         graphics_root.Units = 'pixels';
         ssz = graphics_root.ScreenSize;
         set(app.Figure, 'Units', 'pixels');
         app.Figure.Units = 'pixels';
         uisz = app.Figure.Position;
         if size(app.InputImage, 1)
            fsz = app.OutputImageFigure.Position;
            bc = (fsz(4) - uisz(4)) / 3;
            if bc > 0
               bc = bc + fsz(2);
            else
               bc = fsz(2) + fsz(4) - uisz(4) - 10;
            end
            lc = fsz(1) + (size(app.InputImage, 2) / 4) + (3 * fsz(3) / 4);
            lc = min(lc, ssz(3) - uisz(3) - 10);
            app.Figure.Position = [lc bc 463 391];
         else
            bc = round((ssz(4) - uisz(4)) / 2) - 10;
            lc = round((ssz(3) - uisz(3)) / 2) - 10;
            app.Figure.Position = [lc bc uisz(3) uisz(4)];
         end
         
         if verLessThan('matlab','9.6')
            warning("Interactive Color Editor requires " + ...
               "MATLAB R2019a to be fully functional. " + ...
               "Pseudo-color bar and full-color bar will not be displayed.")
         else
            app.GrayBarImage = uiimage('Parent',app.Figure,'Position',[315 143 128 31]);
            app.ColorBarImage = uiimage('Parent',app.Figure,'Position',[315 79 128 31]);
         end
         
         app.CurveAxes.XLim = [0 1];
         app.CurveAxes.YLim = [0 1];
         app.CurveAxes.Toolbar.Visible = 'off';
         
         graph(app);
         render(app);
      end

      % Value changed function: ShowCDFCheckbox
      function ShowCDFCheckbox_Callback(app, event)
            %  Accept CDF (cumulative distribution function) display parameter
            %  for selected color component and redraw mapping function if
            %  smoothing is on. If set, clear CDF display.
            
            if event.Value == 1
               app.CurveCDFStates(app.CurveIndex) = 1;
               app.ShowPDFCheckbox.Value = 0;
               app.CurvePDFStates(app.CurveIndex) = 0;
            else
               app.CurveCDFStates(app.CurveIndex) = 0;
            end
            graph(app);
      end

      % Value changed function: ColorComponentsSelector
      function ColorComponentsSelector_Callback(app, event)
            %  Accept color component selection, update component specific
            %  parameters on GUI, and draw the selected mapping function.
            
            c = find(strcmp(event.Value,app.ColorComponentsSelector.Items));
            app.CurveIndex = c;
            app.SmoothCheckbox.Value = app.CurveSmoothingStates(c);
            app.SlopeCheckbox.Value = app.CurveEndSlopeControlStates(c);
            app.ShowPDFCheckbox.Value = app.CurvePDFStates(c);
            app.ShowCDFCheckbox.Value = app.CurveCDFStates(c);
            graph(app);
      end

      % Window button down function: Figure
      function Figure_WindowButtonDownFcn(app, event)
            %  Start mapping function control point editing. Do move, add, or
            %  delete for left, middle, and right button mouse clicks ('normal',
            %  'extend', and 'alt' cases) over plot area.
            
            app.PlotAreaParameters = get(app.CurveAxes, 'Position');
            [inplot, x, y] = cursor(app);
            if inplot
               nodes = app.Sets{app.CurveIndex};
               below = find(x >= nodes(:, 1),1,'last');      
               above = min(below + 1, size(nodes, 1));
               if (x - nodes(below, 1)) > (nodes(above, 1) - x)
                  node = above;
               else
                  node = below;
               end
               deletednode = 0;
            
               % switch get(hObject, 'SelectionType')
               switch app.Figure.SelectionType
               case 'normal'
                  if node == above
                     above = min(above + 1, size(nodes, 1));
                  elseif node == below
                     below = max(below - 1, 1);
                  end
                  if node == size(nodes, 1)
                     below = above;
                  elseif node == 1
                     above = below;
                  end
                  if x > nodes(above, 1)
                     x = nodes(above, 1);
                  elseif x < nodes(below, 1)
                     x = nodes(below, 1);
                  end
                  app.SelectedControlPoint = node;     
                  app.MouseUpDownState = 'down';
                  app.NodeBelowControlPoint = below;   
                  app.NodeAboveControlPoint = above;
                  nodes(node, :) = [x y];
               case 'extend'
                  if ~any(nodes(:, 1) == x)
                     nodes = [nodes(1:below, :); [x y]; nodes(above:end, :)];
                     app.SelectedControlPoint = above;   
                     app.MouseUpDownState = 'down';
                     app.NodeBelowControlPoint = below;  
                     app.NodeAboveControlPoint = above + 1;
                  end
               case 'alt'
                  if (node ~= 1) && (node ~= size(nodes, 1))
                     nodes(node, :) = [];   
                     deletednode = 1;
                  end
                  app.SelectedControlPoint = 0;
                  app.InputTextField.Text = '';
                  app.OutputTextField.Text = '';
               end
            
               app.Sets{app.CurveIndex} = nodes;
               graph(app);
               if deletednode
                  render(app);
               end
            end
      end

      % Window button motion function: Figure
      function Figure_WindowButtonMotionFcn(app, event)
            %  Do nothing unless a mouse 'down' event has occurred. If it has,
            %  modify control point and make new mapping function.
            
            if ~strcmpi(app.MouseUpDownState, 'down')
               return;
            end
            [inplot, x, y] = cursor(app);
            if inplot
               nodes = app.Sets{app.CurveIndex};
               nudge = app.CurveSmoothingStates(app.CurveIndex) / 256;
               if (app.SelectedControlPoint ~= 1) && (app.SelectedControlPoint ~= size(nodes, 1))
                  if x >= nodes(app.NodeAboveControlPoint, 1)
                     x = nodes(app.NodeAboveControlPoint, 1) - nudge;
                  elseif x <= nodes(app.NodeBelowControlPoint, 1)
                     x = nodes(app.NodeBelowControlPoint, 1) + nudge;
                  end
               else
                  if x > nodes(app.NodeAboveControlPoint, 1)
                     x = nodes(app.NodeAboveControlPoint, 1);
                  elseif x < nodes(app.NodeBelowControlPoint, 1)
                     x = nodes(app.NodeBelowControlPoint, 1);
                  end
               end
               nodes(app.SelectedControlPoint, :) = [x y];
               app.Sets{app.CurveIndex} = nodes;
               graph(app);
            end
      end

      % Window button up function: Figure
      function Figure_WindowButtonUpFcn(app, event)
            %  Terminate ongoing control point move or add operation. Clear
            %  coordinate text below plot and update display.
            
            update = strcmpi(app.MouseUpDownState, 'down');
            app.MouseUpDownState = 'up';      
            app.SelectedControlPoint = 0;
            if update
               app.InputTextField.Text = '';
               app.OutputTextField.Text = '';
               render(app);
            end
      end

      % Value changed function: MapBarsCheckbox
      function MapBarsCheckbox_Callback(app, event)
            %  Accept changes to bar map enable state and redraw bars.
             
            app.BarMapEnabled = event.Value;
            render(app);
      end

      % Value changed function: MapImageCheckbox
      function MapImageCheckbox_Callback(app, event)
            %  Accept changes to the image map state and redraw image.
                 
            app.ImageMapEnabled = event.Value;
            render(app);
      end

      % Value changed function: ShowPDFCheckbox
      function ShowPDFCheckbox_Callback(app, event)
            %  Accept PDF (probability density function or histogram) display
            %  parameter for currently selected color component and redraw
            %  mapping function if smoothing is on. If set, clear CDF display.
            
            if event.Value == 1
               app.CurvePDFStates(app.CurveIndex) = 1;
               app.ShowCDFCheckbox.Value = 0;
               app.CurveCDFStates(app.CurveIndex) = 0;
            else
               app.CurvePDFStates(app.CurveIndex) = 0;
            end
            graph(app);
      end

      % Button pushed function: ResetCurveOptionsButton
      function ResetCurveOptionsButton_Callback(app, event)
            %  Init all display parameters for currently selected color
            %  component, make map 1:1, and redraw it.
            
            app.Sets{app.CurveIndex} = [0 0; 1 1];
            c = app.CurveIndex;
            app.CurveSmoothingStates(c) = 0;  
            app.SmoothCheckbox.Value = 0;
            app.CurveEndSlopeControlStates(c) = 0;   
            app.SlopeCheckbox.Value = 0;
            app.CurvePDFStates(c) = 0;     
            app.ShowPDFCheckbox.Value = 0;
            app.CurveCDFStates(c) = 0;     
            app.ShowCDFCheckbox.Value = 0;
            graph(app);      
            render(app);
      end

      % Button pushed function: ResetAllButton
      function ResetAllButton_Callback(app, event)
            %  Init display parameters for color components, make all maps 1:1,
            %  and redraw display.
            
            for c = 1:4
               app.CurveSmoothingStates(c) = 0;      
               app.CurveEndSlopeControlStates(c) = 0;
               app.CurvePDFStates(c) = 0;         
               app.CurveCDFStates(c) = 0;
               app.Sets{c} = [0 0; 1 1];
            end
            app.SmoothCheckbox.Value = 0;
            app.SlopeCheckbox.Value = 0;
            app.ShowPDFCheckbox.Value = 0;
            app.ShowCDFCheckbox.Value = 0;
            graph(app);      
            render(app);
      end

      % Value changed function: SlopeCheckbox
      function SlopeCheckbox_Callback(app, event)
            %  Accept slope clamp for currently selected color component and
            %  draw function if smoothing is on.
            
            if event.Value == 1
               app.CurveEndSlopeControlStates(app.CurveIndex) = 1;
            else
               app.CurveEndSlopeControlStates(app.CurveIndex) = 0;
            end
            if app.CurveSmoothingStates(app.CurveIndex)
               graph(app);      
               render(app);
            end
      end

      % Value changed function: SmoothCheckbox
      function SmoothCheckbox_Callback(app, event)
            %  Accept smoothing parameter for currently selected color
            %  component and redraw mapping function.
            
            if event.Value == 1
               app.CurveSmoothingStates(app.CurveIndex) = 1;
               nodes = app.Sets{app.CurveIndex};
               nodes = spreadout(app,nodes);
               app.Sets{app.CurveIndex} = nodes;
            else
               app.CurveSmoothingStates(app.CurveIndex) = 0;
            end
            graph(app);      
            render(app);
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.Figure = uifigure('Visible', 'off');
         app.Figure.Name = 'ICE - Interactive Color Editor';
         app.Figure.WindowButtonDownFcn = createCallbackFcn(app, @Figure_WindowButtonDownFcn, true);
         app.Figure.WindowButtonUpFcn = createCallbackFcn(app, @Figure_WindowButtonUpFcn, true);
         app.Figure.WindowButtonMotionFcn = createCallbackFcn(app, @Figure_WindowButtonMotionFcn, true);
         app.CurveAxes = uiaxes(app.Figure);
         app.CurveAxes.XLim = [0 1];
         app.CurveAxes.YLim = [0 1];
         app.CurveAxes.XTick = [0 0.25 0.5 0.75 1];
         app.CurveAxes.YTick = [0 0.25 0.5 0.75 1];
         app.ComponentLabel = uilabel(app.Figure);
         app.ComponentLabel.Text = 'Component:';
         app.InputFieldLabel = uilabel(app.Figure);
         app.InputFieldLabel.Text = 'Input:';
         app.OutputFieldLabel = uilabel(app.Figure);
         app.OutputFieldLabel.Text = 'Output:';
         app.InputTextField = uilabel(app.Figure);
         app.InputTextField.Text = '';
         app.OutputTextField = uilabel(app.Figure);
         app.OutputTextField.Text = '';
         app.ResetAllButton = uibutton(app.Figure, 'push');
         app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButton_Callback, true);
         app.ResetAllButton.Text = 'Reset All';
         app.BlueValueDisplay = uilabel(app.Figure);
         app.BlueValueDisplay.Text = '';
         app.GreenValueDisplay = uilabel(app.Figure);
         app.GreenValueDisplay.Text = '';
         app.RedValueDisplay = uilabel(app.Figure);
         app.RedValueDisplay.Text = '';
         app.PseudoColorBarLabel = uilabel(app.Figure);
         app.PseudoColorBarLabel.Text = 'Pseudo-color Bar';
         app.FullColorBarLabel = uilabel(app.Figure);
         app.FullColorBarLabel.Text = 'Full-color Bar';
         app.MapBarsCheckbox = uicheckbox(app.Figure);
         app.MapBarsCheckbox.ValueChangedFcn = createCallbackFcn(app, @MapBarsCheckbox_Callback, true);
         app.MapBarsCheckbox.Text = 'Map Bars';
         app.MapBarsCheckbox.Value = true;
         app.MapImageCheckbox = uicheckbox(app.Figure);
         app.MapImageCheckbox.ValueChangedFcn = createCallbackFcn(app, @MapImageCheckbox_Callback, true);
         app.MapImageCheckbox.Text = 'Map Image';
         app.MapImageCheckbox.Value = true;
         app.CurveOptionsPanel = uipanel(app.Figure);
         app.CurveOptionsPanel.Title = 'Curve';
         app.SmoothCheckbox = uicheckbox(app.CurveOptionsPanel);
         app.SmoothCheckbox.ValueChangedFcn = createCallbackFcn(app, @SmoothCheckbox_Callback, true);
         app.SmoothCheckbox.Text = 'Smooth';
         app.ResetCurveOptionsButton = uibutton(app.CurveOptionsPanel, 'push');
         app.ResetCurveOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCurveOptionsButton_Callback, true);
         app.ResetCurveOptionsButton.Text = 'Reset';
         app.SlopeCheckbox = uicheckbox(app.CurveOptionsPanel);
         app.SlopeCheckbox.ValueChangedFcn = createCallbackFcn(app, @SlopeCheckbox_Callback, true);
         app.SlopeCheckbox.Text = 'Clamp Ends';
         app.ShowPDFCheckbox = uicheckbox(app.CurveOptionsPanel);
         app.ShowPDFCheckbox.ValueChangedFcn = createCallbackFcn(app, @ShowPDFCheckbox_Callback, true);
         app.ShowPDFCheckbox.Text = 'Show PDF';
         app.ShowCDFCheckbox = uicheckbox(app.CurveOptionsPanel);
         app.ShowCDFCheckbox.ValueChangedFcn = createCallbackFcn(app, @ShowCDFCheckbox_Callback, true);
         app.ShowCDFCheckbox.Text = 'Show CDF';
         app.ColorComponentsSelector = uidropdown(app.Figure);
         app.ColorComponentsSelector.Items = {'RGB', 'Red', 'Green', 'Blue'};
         app.ColorComponentsSelector.ValueChangedFcn = createCallbackFcn(app, @ColorComponentsSelector_Callback, true);
         app.ColorComponentsSelector.Value = 'RGB';
         % Show the figure after all components are created
         app.Figure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = iceControlPanel(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.Figure)

         % Execute the startup function
         runStartupFcn(app, @(app)ice_OpeningFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.Figure)
      end
   end
end